classdef seeVR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NuiregactLabel                  matlab.ui.control.Label
        SmoothingactLabel               matlab.ui.control.Label
        DenoisingactLabel               matlab.ui.control.Label
        registerfunctoanatButton        matlab.ui.control.Button
        selectinputdirectoryButton      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputdataTab                    matlab.ui.container.Tab
        slice_Slider_2                  matlab.ui.control.Slider
        Label_3                         matlab.ui.control.Label
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        pctileEdit                      matlab.ui.control.EditField
        pctilecutoffEditFieldLabel      matlab.ui.control.Label
        remLV                           matlab.ui.control.Button
        slice_Slider                    matlab.ui.control.Slider
        Label_2                         matlab.ui.control.Label
        volume_Slider                   matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        UIAxes_tSNR                     matlab.ui.control.UIAxes
        UIAxes_mask                     matlab.ui.control.UIAxes
        UIAxes_source                   matlab.ui.control.UIAxes
        DenoisingTab                    matlab.ui.container.Tab
        ErrorLabel                      matlab.ui.control.Label
        LicensestatusLabel              matlab.ui.control.Label
        WhichmaskDropDown               matlab.ui.control.DropDown
        WhichmaskDropDownLabel          matlab.ui.control.Label
        Panel_denoising                 matlab.ui.container.Panel
        FamilynumberDropDown            matlab.ui.control.DropDown
        FamilynumberDropDownLabel       matlab.ui.control.Label
        WavfamDropDown                  matlab.ui.control.DropDown
        WaveletfamilyDropDownLabel      matlab.ui.control.Label
        WavlevelEditField               matlab.ui.control.NumericEditField
        WaveletlevelEditFieldLabel      matlab.ui.control.Label
        MethodwavDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ThresruleDropDown               matlab.ui.control.DropDown
        ThresholdruleDropDownLabel      matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LeveldepButton                  matlab.ui.control.RadioButton
        LevelindepButton                matlab.ui.control.RadioButton
        NoiseestLabel                   matlab.ui.control.Label
        WindowsizeEditField             matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel        matlab.ui.control.Label
        Smoothingfactor01EditField      matlab.ui.control.NumericEditField
        Smoothingfactor01EditFieldLabel  matlab.ui.control.Label
        MethodmovDropDown               matlab.ui.control.DropDown
        MethodDropDown_2Label           matlab.ui.control.Label
        FilterorderEditField            matlab.ui.control.NumericEditField
        FilterorderEditFieldLabel       matlab.ui.control.Label
        FrequencybpmaxEditField         matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        FrequencybpminEditField         matlab.ui.control.NumericEditField
        FrequencybandpassEditFieldLabel  matlab.ui.control.Label
        DenoisingmethodDropDown         matlab.ui.control.DropDown
        DenoisingmethodDropDownLabel    matlab.ui.control.Label
        RemprevdenButton                matlab.ui.control.Button
        ApplydenButton                  matlab.ui.control.Button
        DenoisingLabel                  matlab.ui.control.Label
        UIAxes_noiseremoval             matlab.ui.control.UIAxes
        Denoising2Tab                   matlab.ui.container.Tab
        CurrentsliceLabel               matlab.ui.control.Label
        SliceSliderDen                  matlab.ui.control.Slider
        SliceSliderLabel                matlab.ui.control.Label
        PlotdenoisedimageButton         matlab.ui.control.Button
        UIAxes_denVoxelGraph            matlab.ui.control.UIAxes
        UIAxes_denData                  matlab.ui.control.UIAxes
        SmoothingTab                    matlab.ui.container.Tab
        smoothsliceLabel                matlab.ui.control.Label
        SliceSlider_smoothing           matlab.ui.control.Slider
        SliceSliderLabel_2              matlab.ui.control.Label
        StatussmoothingLabel            matlab.ui.control.Label
        Panel_smoothing                 matlab.ui.container.Panel
        SmoothenLabel                   matlab.ui.control.Label
        ButtonGroup_sData               matlab.ui.container.ButtonGroup
        DenoiseddataButton              matlab.ui.control.RadioButton
        OriginaldataButton              matlab.ui.control.RadioButton
        SigmarangeEditField             matlab.ui.control.NumericEditField
        SigmarangeEditFieldLabel        matlab.ui.control.Label
        FilterwidthmmEditField          matlab.ui.control.NumericEditField
        FilterwidthmmEditFieldLabel     matlab.ui.control.Label
        FWHM_x                          matlab.ui.control.NumericEditField
        FWHMofkernelmmEditFieldLabel    matlab.ui.control.Label
        SpatialdimensionsDropDown       matlab.ui.control.DropDown
        SpatialdimensionsDropDownLabel  matlab.ui.control.Label
        SmoothingmethodDropDown         matlab.ui.control.DropDown
        SmoothingmethodDropDownLabel    matlab.ui.control.Label
        RemovesmoothingButton           matlab.ui.control.Button
        ApplysmoothingButton            matlab.ui.control.Button
        FWHM_z                          matlab.ui.control.NumericEditField
        zLabel                          matlab.ui.control.Label
        FWHM_y                          matlab.ui.control.NumericEditField
        yLabel                          matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        SmoothingLabel                  matlab.ui.control.Label
        UIAxes_smoothing                matlab.ui.control.UIAxes
        NuisanceregressionTab           matlab.ui.container.Tab
        PerformnuisanceregressionButton  matlab.ui.control.Button
        LoadnuisanceparametersButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        CVRandhemodynamiclagTab         matlab.ui.container.Tab
        Tab                             matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
        dirs % data directories
        files % data filenames
        data % data
        dp %general data properties
        chosenmask % Chosen mask for denoising
    end

    methods (Access = private)

        function [] = plotSourceData(app)

            orient = app.OrientationDropDown.Value;

            %callback stored slice value
            switch orient
                case 'ORI1'
                    ratio = size(app.data.tSNR,1)/size(app.data.tSNR,2);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_Slider.Limits = [1 size(app.data.tSNR,3)];
                    if app.dp.hasrunORI1 == 0
                        if app.slice_Slider.Value > size(app.data.tSNR,3)
                            app.slice_Slider.Value = roundsize(app.data.tSNR,3)/2;
                            app.dp.hasrunORI1 = 1;
                            app.dp.hasrunORI2 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI2'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,1);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    app.slice_Slider.Limits = [1 size(app.data.tSNR,2)];
                    if app.dp.hasrunORI2 == 0
                        if app.slice_Slider.Value > size(app.data.tSNR,2)
                            app.slice_Slider.Value = roundsize(app.data.tSNRt,2)/2;
                            app.dp.hasrunORI2 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI3'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,2);
                    app.UIAxes_source.PlotBoxAspectRatio = [1, ratio,  1];
                    %set new slider limits
                    if app.dp.hasrunORI3 == 0
                        app.slice_Slider.Limits = [1 size(app.data.tSNR,1)];
                        if app.slice_Slider > size(app.data.tSNR,1)
                            app.slice_Slider.Value = roundsize(app.data.tSNR,1)/2;
                            app.dp.hasrunORI3 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI2 = 0;
                        end
                    end
            end
            slice = round(get(app.slice_Slider,'Value'));
            vol = round(get(app.volume_Slider,'Value'));

            switch orient
                case 'ORI1'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,squeeze(app.data.sourceData(:,:,slice,vol)));
                case 'ORI2'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,imrotate(squeeze(app.data.sourceData(:,slice,:,vol)),90));
                case 'ORI3'
                    cla(app.UIAxes_source);
                    imagesc(app.UIAxes_source,imrotate(squeeze(app.data.sourceData(slice,:,:,vol)),90));
            end
            app.UIAxes_source.Colormap = bone(128);
        end

        % function to plot SNR map with removed voxel overlay

        function [] = plottSNR(app)

            orient = app.OrientationDropDown.Value;

            %callback stored slice value
            switch orient
                case 'ORI1'
                    ratio = size(app.data.tSNR,1)/size(app.data.tSNR,2);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    app.slice_Slider_2.Limits = [1 size(app.data.tSNR,3)];
                    if app.dp.hasrunORI1 == 0
                        if app.slice_Slider_2.Value > size(app.data.tSNR,3)
                            app.slice_Slider_2.Value = roundsize(app.data.tSNR,3)/2;
                            app.dp.hasrunORI1 = 1;
                            app.dp.hasrunORI2 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI2'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,1);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1  ratio  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    app.slice_Slider_2.Limits = [1 size(app.data.tSNR,2)];
                    if app.dp.hasrunORI2 == 0
                        if app.slice_Slider_2.Value > size(app.data.tSNR,2)
                            app.slice_Slider_2.Value = roundsize(app.data.tSNRt,2)/2;
                            app.dp.hasrunORI2 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI3 = 0;
                        end
                    end
                case 'ORI3'
                    ratio = size(app.data.tSNR,3)/size(app.data.tSNR,2);
                    app.UIAxes_tSNR.PlotBoxAspectRatio = [1, ratio,  1];
                    app.UIAxes_mask.PlotBoxAspectRatio = app.UIAxes_tSNR.PlotBoxAspectRatio;
                    %set new slider limits
                    if app.dp.hasrunORI3 == 0
                        app.slice_Slider_2.Limits = [1 size(app.data.tSNR,1)];
                        if app.slice_Slider_2 > size(app.data.tSNR,1)
                            app.slice_Slider_2.Value = roundsize(app.data.tSNR,1)/2;
                            app.dp.hasrunORI3 = 1;
                            app.dp.hasrunORI1 = 0;
                            app.dp.hasrunORI2 = 0;
                        end
                    end
            end
            slice = round(get(app.slice_Slider_2,'Value'));

            if app.dp.LVfilter == 0

                switch orient % no large vessel removal
                    case 'ORI1'
                        cla(app.UIAxes_tSNR);
                        imagesc(app.UIAxes_tSNR,squeeze(app.data.tSNR(:,:,slice)));
                    case 'ORI2'
                        cla(app.UIAxes_tSNR);
                        tmp = imrotate(squeeze(app.data.tSNR(:,slice,:)),90);
                        imagesc(app.UIAxes_tSNR,tmp); clear tmp
                    case 'ORI3'
                        cla(app.UIAxes_tSNR);
                        tmp = imrotate(squeeze(app.data.tSNR(slice,:,:)),90);
                        imagesc(app.UIAxes_tSNR,tmp); clear tmp
                end
                app.UIAxes_tSNR.Colormap = bone(128);

            else
                app.UIAxes_tSNR.CLim = [0 100];
                switch orient % overlay removed voxels
                    case 'ORI1'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = squeeze(app.data.tSNR(:,:,slice));
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = squeeze(app.data.mask.LVmask(:,:,slice));
                        tmp(tmp == 0) = NaN;
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;
                        linkaxes([app.UIAxes_tSNR app.UIAxes_mask]);
                    case 'ORI2'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = imrotate(squeeze(app.data.tSNR(:,slice,:)),90);
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = imrotate(squeeze(app.data.mask.LVmask(:,slice,:)),90);
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;
                        linkaxes([app.UIAxes_tSNR app.UIAxes_mask]);
                    case 'ORI3'
                        cla(app.UIAxes_tSNR); cla(app.UIAxes_mask);
                        tmp = imrotate(squeeze(app.data.tSNR(slice,:,:)),90);
                        f1=imagesc(app.UIAxes_tSNR,tmp,"AlphaData",0.6); clear tmp
                        app.UIAxes_tSNR.Colormap = bone(128);
                        tmp = imrotate(squeeze(app.data.mask.LVmask(slice,:,:)),90);
                        f2=imagesc(app.UIAxes_mask,tmp);
                        app.UIAxes_mask.Colormap = magma(2);
                        app.UIAxes_mask.Position = app.UIAxes_tSNR.Position;
                        linkaxes([app.UIAxes_tSNR app.UIAxes_mask]);
                end
            end
        end


        function makemaskdropdown(app)
            if isempty(app.files.func_brain) == 0
                app.WhichmaskDropDown.Items = [app.WhichmaskDropDown.Items 'Whole brain'];
            end
            
            if isempty(app.files.func_GM) == 0
                app.WhichmaskDropDown.Items = [app.WhichmaskDropDown.Items 'Grey matter'];
            end
            
            if isempty(app.files.func_WM) == 0
                app.WhichmaskDropDown.Items = [app.WhichmaskDropDown.Items 'White matter'];
            end
                
            if isempty(app.files.func_CSF) == 0
                app.WhichmaskDropDown.Items = [app.WhichmaskDropDown.Items 'CSF'];
            end    
            
            if isempty(app.files.func_lesion) == 0
                app.WhichmaskDropDown.Items = [app.WhichmaskDropDown.Items 'Lesion']; 
            end
                
            if isempty(app.files.func_edema) == 0
                app.WhichmaskDropDown.Items = [app.WhichmaskDropDown.Items 'Edema'];
            end
            
            app.WhichmaskDropDown.Items = app.WhichmaskDropDown.Items(2:end);

            
        end
        
        function readmaskdropdown(app)
            if strcmp(app.WhichmaskDropDown.Value, 'Grey matter')
                app.chosenmask = app.data.mask.GM;
                
            elseif strcmp(app.WhichmaskDropDown.Value, 'White matter')
                app.chosenmask = app.data.mask.WM;
                
            elseif strcmp(app.WhichmaskDropDown.Value, 'CSF')
                app.chosenmask = app.data.mask.CSF;
                
            elseif strcmp(app.WhichmaskDropDown.Value, 'Whole brain')
                app.chosenmask = app.data.mask.WB;
                
            elseif strcmp(app.WhichmaskDropDown.Value, 'Lesion')
                app.chosenmask = app.data.mask.lesion;
                
            elseif strcmp(app.WhichmaskDropDown.Value, 'Edema')
                app.chosenmask = app.data.mask.edema;
                
            end


        end
        
        function defaultfilterorder(app)
            %set filter order to default
            global opts
            
            [ref_voxels ref_coordinates] = grabTimeseries(app.data.sourceData, app.data.mask.GM);
            
            Fs = 1/opts.TR;
            mean_ts = detrend(mean(ref_voxels,1)); % detrend reference time-series
            Lowf = 0.000001; Highf = 0.1164; %Hz
            
            SSres = []; BP = [];
            for forder = 1:4
                [b,a] = butter(forder,2*[Lowf, Highf]/Fs);
                BP(forder,:) = filtfilt(double(b),double(a),double(mean_ts));
                SSres(forder,:)= sum((mean_ts - BP(forder,:).^2),2);
            end
            %select closest filter
            [~,I] = min(SSres);
            opts.filter_order = I;
            
            app.FilterorderEditField.Value = I;
            
        end
        
        function txt = localDcmFcn(~,event,ax)
            % find indices to closest x and y data values.  This requires the
            % number of values in XData and YData to agree with CData size.
            [~, xidx] = min(abs(event.Target.XData - event.Position(1)));
            [~, yidx] = min(abs(event.Target.YData - event.Position(2)));
            colorbarValue = event.Target.CData(yidx, xidx); 
            colormapIdx = round((colorbarValue - ax.CLim(1))/(ax.CLim(2)-ax.CLim(1)) * (size(ax.Colormap,1)-1) +1);
            txt = sprintf('[X,Y]: [%.2g, %.2g]\nCBar Value: %.2g\n[R,G,B]: [%.4g, %.4g, %.4g]', ...
                event.Position, colorbarValue, ax.Colormap(colormapIdx,:));
            
        end
        
        function defaultsigmarange(app)
            if ndims(app.data.sourceData) > 3
                app.SigmarangeEditField.Value = double(10*ROIstd(mean(app.data.sourceData(:,:,:,1:10),4),app.data.mask.WB)); 
            else
                tmp = app.data.sourceData.*app.data.mask.WB;
                tmp = tmp(:);
                tmp(tmp == 0) = [];
            app.SigmarangeEditField.Value = 10*std(tmp); 
            end   
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %run pathdef function
            global opts;
            [dirs, files] = seeVR_set_variables;
            % set folder and filename tags
            app.dirs = dirs;
            app.files = files;
        end

        % Button pushed function: selectinputdirectoryButton
        function selectinputdirectoryButtonPushed(app, event)
            %initialize the global opts structure
            global opts;
            %select input directory
            app.dirs.datapath = fullfile(uigetdir);
            [app.data, app.dp] = check_loadData(app.dirs, app.files);
            %calculate temporal SNR
            [voxel_ts, coordinates] = grabTimeseries(app.data.sourceData, app.data.mask.WB);
            SD = nanstd(voxel_ts,0,2);
            MN = nanmean(voxel_ts,2);
            tSNR = zeros([1 numel(app.data.mask.WB)]);
            tSNR(coordinates) = MN./SD;
            app.data.tSNR = reshape(tSNR, size(app.data.mask.WB));

            % set visibility after loading
            app.UIAxes_source.Visible = 'on';
            app.volume_Slider.Visible = 'on';
            app.slice_Slider.Visible = 'on';
            app.slice_Slider_2.Visible = 'on';
            app.remLV.Visible = 'on';
            app.pctileEdit.Visible = 'on';
            app.OrientationDropDown.Visible = 'on';
            app.OrientationDropDownLabel.Visible = 'on';

            if app.dp.isT1; app.registerfunctoanatButton.Visible = 'on'; end

            %set limits of volume and slice sliders
            set(app.volume_Slider,'Limits',[1 app.dp.vols]);
            set(app.volume_Slider,'Value',1);
            set(app.slice_Slider,'Limits',[1 app.dp.zdim]);
            set(app.slice_Slider,'Value',ceil(app.dp.zdim/2));
            set(app.slice_Slider_2,'Limits',[1 app.dp.zdim]);
            set(app.slice_Slider_2,'Value',ceil(app.dp.zdim/2));


            %display source image
            app.dp.hasrunORI1 = 0; app.dp.hasrunORI2 = 0; app.dp.hasrunORI3 = 0;
            app.dp.LVfilter = 0;
            plotSourceData(app)
            plottSNR(app)
            
            %DENOISING TAB
            %set winsize default and determine limit of 10% 
            app.WindowsizeEditField.Value = round(0.05*size(app.data.sourceData,4));
            app.WindowsizeEditField.Limits = [0 0.1*size(app.data.sourceData,4)];
            
            %set up denoising plot with original timeseries data
            makemaskdropdown(app)
            readmaskdropdown(app)
            
            TS1 = meanTimeseries(app.data.sourceData, app.chosenmask);
            plot(app.UIAxes_noiseremoval, TS1, 'k');
            legend(app.UIAxes_noiseremoval, 'original')
            
            app.data.densourceData = [];
            
            defaultfilterorder(app)
            
            %set frequency bandpass min and max default
            app.FrequencybpminEditField.Limits = [0.00001, 1/(2*opts.TR)];
            app.FrequencybpmaxEditField.Limits = [0.00002, 1/(2*opts.TR)];
            
            %DENOISING 2 TAB
            set(app.SliceSliderDen,'Limits',[1 app.dp.zdim]);
            set(app.SliceSliderDen,'Value',ceil(app.dp.zdim/2));
            app.CurrentsliceLabel.Text = append('Current slice: ', int2str(app.SliceSliderDen.Value));
            
            %SMOOTHING TAB
            defaultsigmarange(app)
            set(app.SliceSlider_smoothing,'Limits',[1 app.dp.zdim]);
            set(app.SliceSlider_smoothing,'Value',ceil(app.dp.zdim/2));
            app.smoothsliceLabel.Text = append('Current slice: ', int2str(app.SliceSlider_smoothing.Value));


        end

        % Value changed function: volume_Slider
        function volume_SliderValueChanged(app, event)
            value = round(app.volume_Slider.Value);
            plotSourceData(app)
            disp(['volume number: ',int2str(value)])
        end

        % Value changed function: slice_Slider
        function slice_SliderValueChanged(app, event)
            value = round(app.slice_Slider.Value);
            plotSourceData(app)
            disp(['slice number: ',int2str(value)])
        end

        % Button pushed function: registerfunctoanatButton
        function registerfunctoanatButtonPushed(app, event)
            % register mean BOLD data to anatomical via dedicated UI
            global opts;
            func2anat_lin(app.data.sourceData,app.data.anat,app.dirs.elastix,opts);

        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            plotSourceData(app)
            plottSNR(app)
        end

        % Value changed function: slice_Slider_2
        function slice_Slider_2ValueChanged(app, event)
            value = round(app.slice_Slider_2.Value);
            plottSNR(app)
            disp(['slice number: ',int2str(value)])
        end

        % Button pushed function: remLV
        function remLVButtonPushed(app, event)
            global opts;
            opts.LVpercentile = str2double(app.pctileEdit.Value);
            opts.verbose = 0;
            [app.data.mask.mmask] = remLV(imrotate(app.data.sourceData,270),imrotate(app.data.mask.WB,270),opts);
            app.data.mask.mmask = logical(imrotate(app.data.mask.mmask,90));
            app.dp.LVfilter = 1; 
            % calculate LVmask
            app.data.mask.LVmask = abs(app.data.mask.WB - app.data.mask.mmask).*app.data.mask.WB;
            plottSNR(app)
        end

        % Value changed function: DenoisingmethodDropDown
        function DenoisingmethodDropDownValueChanged(app, event)
            value = app.DenoisingmethodDropDown.Value;
                    if strcmp(value, 'Wavelet')
                        app.ButtonGroup.Visible = 'on';
                        app.NoiseestLabel.Visible = 'on';
                        app.ThresruleDropDown.Visible = 'on';
                        app.ThresholdruleDropDownLabel.Visible = 'on';
                        app.WavlevelEditField.Visible = 'on';
                        app.WaveletlevelEditFieldLabel.Visible = 'on';
                        app.WavfamDropDown.Visible = 'on';
                        app.WaveletfamilyDropDownLabel.Visible = 'on';
                        app.FamilynumberDropDown.Visible = 'on';
                        app.FamilynumberDropDownLabel.Visible = 'on';
                        app.MethodwavDropDown.Visible = 'on';
                        app.MethodDropDownLabel.Visible = 'on';
                        
                        app.MethodmovDropDown.Visible = 'off';
                        app.MethodDropDown_2Label.Visible = 'off';
                        app.WindowsizeEditField.Visible = 'off';
                        app.WindowsizeEditFieldLabel.Visible = 'off';
                        app.Smoothingfactor01EditField.Visible = 'off';
                        app.Smoothingfactor01EditFieldLabel.Visible = 'off';
                        
                        app.FrequencybpminEditField.Visible = 'off';
                        app.FrequencybandpassEditFieldLabel.Visible = 'off';
                        app.FrequencybpmaxEditField.Visible = 'off';
                        app.FilterorderEditField.Visible = 'off';
                        app.FilterorderEditFieldLabel.Visible = 'off';
                        app.minLabel.Visible = 'off';
                        app.maxLabel.Visible = 'off';
                        
                        
                    elseif strcmp(value, 'Moving window')
                        app.ButtonGroup.Visible = 'off';
                        app.NoiseestLabel.Visible = 'off';
                        app.ThresruleDropDown.Visible = 'off';
                        app.ThresholdruleDropDownLabel.Visible = 'off';
                        app.WavlevelEditField.Visible = 'off';
                        app.WaveletlevelEditFieldLabel.Visible = 'off';
                        app.WavfamDropDown.Visible = 'off';
                        app.WaveletfamilyDropDownLabel.Visible = 'off';
                        app.FamilynumberDropDown.Visible = 'off';
                        app.FamilynumberDropDownLabel.Visible = 'off';
                        app.MethodwavDropDown.Visible = 'off';
                        app.MethodDropDownLabel.Visible = 'off';
                        
                        app.MethodmovDropDown.Visible = 'on';
                        app.MethodDropDown_2Label.Visible = 'on';
                        app.WindowsizeEditField.Visible = 'on';
                        app.WindowsizeEditFieldLabel.Visible = 'on';
                        app.Smoothingfactor01EditField.Visible = 'on';
                        app.Smoothingfactor01EditFieldLabel.Visible = 'on';
                        
                        app.FrequencybpminEditField.Visible = 'off';
                        app.FrequencybandpassEditFieldLabel.Visible = 'off';
                        app.FrequencybpmaxEditField.Visible = 'off';
                        app.FilterorderEditField.Visible = 'off';
                        app.FilterorderEditFieldLabel.Visible = 'off';
                        app.minLabel.Visible = 'off';
                        app.maxLabel.Visible = 'off';
                        
                    elseif strcmp(value, 'Bandpass filter')
                        app.ButtonGroup.Visible = 'off';
                        app.NoiseestLabel.Visible = 'off';
                        app.ThresruleDropDown.Visible = 'off';
                        app.ThresholdruleDropDownLabel.Visible = 'off';
                        app.WavlevelEditField.Visible = 'off';
                        app.WaveletlevelEditFieldLabel.Visible = 'off';
                        app.WavfamDropDown.Visible = 'off';
                        app.WaveletfamilyDropDownLabel.Visible = 'off';
                        app.FamilynumberDropDown.Visible = 'off';
                        app.FamilynumberDropDownLabel.Visible = 'off';
                        app.MethodwavDropDown.Visible = 'off';
                        app.MethodDropDownLabel.Visible = 'off';
                        
                        app.MethodmovDropDown.Visible = 'off';
                        app.MethodDropDown_2Label.Visible = 'off';
                        app.WindowsizeEditField.Visible = 'off';
                        app.WindowsizeEditFieldLabel.Visible = 'off';
                        app.Smoothingfactor01EditField.Visible = 'off';
                        app.Smoothingfactor01EditFieldLabel.Visible = 'off';
                        
                        app.FrequencybpminEditField.Visible = 'on';
                        app.FrequencybandpassEditFieldLabel.Visible = 'on';
                        app.FrequencybpmaxEditField.Visible = 'on';
                        app.FilterorderEditField.Visible = 'on';
                        app.FilterorderEditFieldLabel.Visible = 'on';
                        app.minLabel.Visible = 'on';
                        app.maxLabel.Visible = 'on';
                    end
        end

        % Value changed function: WavfamDropDown
        function WavfamDropDownValueChanged(app, event)
            value = app.WavfamDropDown.Value;
            if strcmp(value, 'db')
                app.FamilynumberDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
                app.FamilynumberDropDown.Value = '4';
            elseif strcmp(value, 'sym')
                app.FamilynumberDropDown.Items = {'2', '3', '4', '5', '6', '7', '8'};
                app.FamilynumberDropDown.Value = '2';
            elseif strcmp(value, 'fk')
                app.FamilynumberDropDown.Items = {'4', '6', '8', '14', '18', '22'};
                app.FamilynumberDropDown.Value = '4';
            elseif strcmp(value, 'bior')
                app.FamilynumberDropDown.Items = {'1.1', '1.3', '1.5', '2.2', '2.4', '2.6','2.8', '3.1', '3.3', '3.5', '3.7', '3.9', '4.4', '5.5', '6.8'};
                app.FamilynumberDropDown.Value = '1.1';
            elseif strcmp(value, 'coif')
                app.FamilynumberDropDown.Items = {'1', '2', '3', '4', '5'};
                app.FamilynumberDropDown.Value = '1';
           
            end
        end

        % Button pushed function: ApplydenButton
        function ApplydenoisingButtonPushed(app, event)
            global opts
            
            app.ErrorLabel.Visible = 'off';
            app.LicensestatusLabel.Text = 'Status: Denoising started, pending...';
            app.LicensestatusLabel.Visible = 'on';
            app.ApplydenButton.Enable = 'off';

            drawnow;
            
            filter = app.DenoisingmethodDropDown.Value;
                        
            try
                app.DenoisingactLabel.Text = 'Denoising: yes';
                app.DenoisingactLabel.FontWeight = 'bold';
               
                if strcmp(filter, 'Wavelet')
                    opts.wavelet = 1;

                    if license('test', 'wavelet_toolbox')
                        wavfam = append(app.WavfamDropDown.Value, app.FamilynumberDropDown.Value)
                        opts.family = wavfam;
                        opts.level = app.WavlevelEditField.Value;
                        opts.DenMeth = app.MethodwavDropDown.Value;
                        opts.ThreshRule = app.ThresruleDropDown.Value;
                        if app.LevelindepButton.Value == 1
                            opts.NoEst = 'LevelIndependent';
                        else
                            opts.NoEst = 'LevelDependent';
                        end
                        
                        opts.verbose = 0;

                        app.data.densourceData = wavDenoise(app.data.sourceData, app.data.mask.WB, opts);
                        app.LicensestatusLabel.Text = 'Status: Wavelet toolbox license accepted, discreet wavelet denoising completed.';
                    end    

         
                elseif strcmp(filter, 'Moving window')
                    opts.wavelet = 0;
                    opts.method = app.MethodmovDropDown.Value;
                    opts.FACTOR = app.Smoothingfactor01EditField.Value;
                    opts.winsize = app.WindowsizeEditField.Value;
                    
                    [xx yy zz dyn] = size(app.data.sourceData);
                    app.data.sourceData = double(app.data.sourceData);
                    app.data.mask.WB = logical(app.data.mask.WB);
                    tf = class(app.data.sourceData);
                    
                    [voxel_ts, coordinates] = grabTimeseries(app.data.sourceData, app.data.mask.WB);
                    
                    voxel_ts = smoothdata(voxel_ts,2,opts.method);
                    app.data.densourceData = reshape(app.data.sourceData, [xx*yy*zz dyn]);
                    app.data.densourceData(coordinates,:) = voxel_ts;
                    app.data.densourceData = reshape(app.data.densourceData, size(app.data.sourceData));
                    
                    app.LicensestatusLabel.Text = 'Status: Moving window denoising completed.';
                    app.LicensestatusLabel.Visible = 'on';
                    
                elseif strcmp(filter, 'Bandpass filter')
                    %max freq should be higher than min freq
                    if app.FrequencybpminEditField.Value > app.FrequencybpmaxEditField.Value
                        app.ErrorLabel.Text = 'Minimum frequency should be lower than maximum frequency';
                        app.ErrorLabel.Visible = 'on';
                        app.ApplydenButton.Enable = 'on';
                        app.LicensestatusLabel.Visible = 'off';
                        return
                    end
    
                    %set minimum freq limit
                    if app.FrequencybpminEditField.Value < 0.00001
                        app.FrequencybpminEditField.Value = 0.00001;
                        app.ErrorLabel.Text = 'Minimum frequency should be higher than 1e-6';
                        app.ErrorLabel.Visible = 'on';
                    end
                    
                    %set maximum freq limit
                    maxFreq = 1/(2*opts.TR);
                    if app.FrequencybpmaxEditField.Value > maxFreq; 
                        app.FrequencybpmaxEditField.Value = maxFreq; 
                        app.ErrorLabel.Text = append('Maximum frequency should be lower or equal to ', int2str(maxFreq));
                        app.ErrorLabel.Visible = 'on';
                    end
                        
                    
                    opts.fpass = [app.FrequencybpminEditField.Value app.FrequencybpmaxEditField.Value];
                    opts.filterorder = app.FilterorderEditField.Value;
    
                    %apply bandpass filtering
                    app.data.densourceData = bandpassfilt(app.data.sourceData, app.data.mask.WB, app.data.mask.GM, app.data.nuisance, opts);
                    
                    app.LicensestatusLabel.Text = 'Status: Bandpass filter denoising completed.';
                    app.LicensestatusLabel.Visible = 'on';
                    
                
                end
   
            %plot original and denoised timeseries plot
            readmaskdropdown(app)
            
            cla(app.UIAxes_noiseremoval);

            TS1 = meanTimeseries(app.data.sourceData, app.chosenmask);
            TS2 = meanTimeseries(app.data.densourceData, app.chosenmask);
            
            hold(app.UIAxes_noiseremoval, 'on');
            plot(app.UIAxes_noiseremoval, TS1, 'k');
            plot(app.UIAxes_noiseremoval, TS2, 'c');
            hold(app.UIAxes_noiseremoval, 'off');
            
            legend(app.UIAxes_noiseremoval, 'original', 'denoised');
            app.DenoiseddataButton.Enable = 'on';
            
            %give status update when an error has occurred
            catch
                app.LicensestatusLabel.Text = 'Status: Error occurred while denoising';
                app.DenoisingactLabel.Text = 'Denoising: no';
                
            end
          
            %enable denoise button again
            app.ApplydenButton.Enable = 'on';

 
        end

        % Button pushed function: RemprevdenButton
        function RemovedenoisingButtonPushed(app, event)
            cla(app.UIAxes_noiseremoval);
            
            TS1 = meanTimeseries(app.data.sourceData, app.chosenmask);
            plot(app.UIAxes_noiseremoval, TS1, 'k');
            legend(app.UIAxes_noiseremoval, 'original')
            
            app.data.densourceData = [];
            
            app.LicensestatusLabel.Visible = 'off';
            app.ErrorLabel.Visible = 'off';
            app.DenoisingactLabel.Text = 'Denoising: no';
            app.DenoisingactLabel.FontWeight = 'normal';
            
            %smoothening can not be done with denoised data
            app.DenoiseddataButton.Enable = 'off';
        end

        % Value changed function: WhichmaskDropDown
        function WhichmaskDropDownValueChanged(app, event)
            cla(app.UIAxes_noiseremoval);
            
            readmaskdropdown(app)
            
            if isempty(app.data.densourceData)
                TS1 = meanTimeseries(app.data.sourceData, app.chosenmask);
                plot(app.UIAxes_noiseremoval, TS1, 'k');
                legend(app.UIAxes_noiseremoval, 'original');
                
            else
                TS1 = meanTimeseries(app.data.sourceData, app.chosenmask);
                TS2 = meanTimeseries(app.data.densourceData, app.chosenmask);
                
                hold(app.UIAxes_noiseremoval, 'on');
                plot(app.UIAxes_noiseremoval, TS1, 'k');
                plot(app.UIAxes_noiseremoval, TS2, 'c');
                hold(app.UIAxes_noiseremoval, 'off');
                legend(app.UIAxes_noiseremoval, 'original', 'denoised');
                
            end
            
        end

        % Button pushed function: PlotdenoisedimageButton
        function PlotdenoisedimageButtonPushed(app, event)
%             uifig = uifigure();
%             uiax = uiaxes(uifig);
%             
            ratio = size(app.data.densourceData,1)/size(app.data.densourceData,2);
            app.UIAxes_denData.PlotBoxAspectRatio = [1, ratio,  1];
            imagesc(app.UIAxes_denData,squeeze(app.data.densourceData(:,:,app.SliceSliderDen.Value,10)));
            app.UIAxes_denData.Colormap = bone(128);
% 
%             I = imagesc(uiax, squeeze(app.data.densourceData(:,:,30,10)));
%             uiax.Colormap = bone(128);
%             
%             dcm = datacursormode(uifig);
%             dcm.Enable = 'on';
%             
%             dcm.UpdateFcn = @(hObj,event,ax)localDcmFcn(hObj,event,uiax);
            
%           
            
        end

        % Value changing function: SliceSliderDen
        function SliceSliderDenValueChanging(app, event)
            changingValue = event.Value;
            slice = round(changingValue);
            app.CurrentsliceLabel.Text = append('Current slice: ', int2str(slice));
            
            cla(app.UIAxes_denData);
            imagesc(app.UIAxes_denData,squeeze(app.data.densourceData(:,:,slice,10)));
            app.UIAxes_denData.Colormap = bone(128);


        end

        % Value changed function: SmoothingmethodDropDown
        function SmoothingmethodDropDownValueChanged(app, event)
            value = app.SmoothingmethodDropDown.Value;
            app.SpatialdimensionsDropDown.Items = {'2D', '3D'};
            app.SpatialdimensionsDropDown.Value = '2D';
            app.xLabel.Visible = 'on';
            app.yLabel.Visible = 'on';
            app.FWHM_y.Visible = 'on';
            app.zLabel.Visible = 'off';
            app.FWHM_z.Visible = 'off';
            
            if strcmp(value, 'Matlab Image Guided')
                app.SpatialdimensionsDropDown.Value = '2D';
                app.SigmarangeEditField.Visible = 'off';
                app.SigmarangeEditFieldLabel.Visible = 'off'; 
                app.SpatialdimensionsDropDown.Items = {'2D'};
                
            elseif strcmp(value, 'Bilateral')
                app.SigmarangeEditField.Visible = 'on';
                app.SigmarangeEditFieldLabel.Visible = 'on';
                
            elseif strcmp(value, 'TIPS')
                app.SigmarangeEditField.Visible = 'on';
                app.SigmarangeEditFieldLabel.Visible = 'on';
                
            elseif(strcmp(value, 'Gaussian'))
                app.SigmarangeEditField.Visible = 'off';
                app.SigmarangeEditFieldLabel.Visible = 'off'; 
                
            elseif(strcmp(value, 'Standard Gaussian'))
                app.SigmarangeEditField.Visible = 'off';
                app.SigmarangeEditFieldLabel.Visible = 'off'; 
                app.FWHM_y.Visible = 'off';
                app.FWHM_z.Visible = 'off';
                app.yLabel.Visible = 'off';
                app.zLabel.Visible = 'off';
                app.xLabel.Visible = 'off';

                
            end
        end

        % Value changed function: SpatialdimensionsDropDown
        function SpatialdimensionsDropDownValueChanged(app, event)
            value = app.SpatialdimensionsDropDown.Value;
            
            if value == '3D'
                app.zLabel.Visible = 'on';
                app.FWHM_z.Visible = 'on';
            else
                app.zLabel.Visible = 'off';
                app.FWHM_z.Visible = 'off';
            end
        end

        % Button pushed function: ApplysmoothingButton
        function ApplysmoothingButtonPushed(app, event)
            global opts
            
            app.ApplysmoothingButton.Enable = 'off';
            app.StatussmoothingLabel.Text = 'Status: Smoothing started, pending...';
            app.StatussmoothingLabel.Visible = 'on';

            drawnow;
            
            if rem(app.FilterwidthmmEditField.Value, 2) == 0
                app.StatussmoothingLabel.Text = 'Error: Filter width should be an odd integer.';
                app.SmoothingactLabel.Text = 'Smoothing: no';
                app.SmoothingactLabel.FontWeight = 'normal';
                app.ApplysmoothingButton.Enable = 'on';
                return
                
            else
                try
                    method = app.SmoothingmethodDropDown.Value;
                    opts.verbose = 0;   
                    opts.filtwidth = app.FilterwidthmmEditField.Value;
                    
                    %determine spatial dimensions and FWHM
                    if strcmp(app.SpatialdimensionsDropDown.Value, '2D')
                        opts.spatialdim = 2;
                        opts.FWHM = [app.FWHM_x.Value app.FWHM_y.Value];
                        
                    else
                        opts.spatialdim = 3;
                        opts.FWHM = [app.FWHM_x.Value app.FWHM_y.Value app.FWHM_z.Value];
                    end
                    
                    guideim = app.data.sourceData(:,:,:,1) %use first BOLD image as guide for smoothing
                    
                    %Check which data needs to be smoothened and apply smoothing for chosen method
                    switch app.ButtonGroup_sData.SelectedObject.Text
                        
                        case 'Original data'
                            switch method
                                case 'Matlab Image Guided'
                                opts.filter = 'imguided';
                                app.data.smoothData = filterData(app.data.sourceData, guideim, app.data.mask.WB, opts);
          
                                case 'Bilateral'
                                opts.filter = 'bilateral';
                                opts.sigma_range = app.SigmarangeEditField.Value; 
                                app.data.smoothData = filterData(app.data.sourceData, guideim, app.data.mask.WB, opts);
                                
                                case 'TIPS'    
                                opts.filter = 'tips';
                                opts.sigma_range = app.SigmarangeEditField.Value;
                                app.data.smoothData = filterData(app.data.sourceData, guideim, app.data.mask.WB, opts);  
                                
                                case 'Standard Gaussian'
                                opts.FWHM = app.FWHM_x.Value;
                                app.data.smoothData = smthData(app.data.sourceData, app.data.mask.WB, opts);
                            
                                case 'Gaussian'
                                opts.filter = 'gaussian';
                                app.data.smoothData = filterData(app.data.sourceData, guideim, app.data.mask.WB, opts);  
                            end
                            
                            app.StatussmoothingLabel.Text = append('Status: ', app.SmoothingmethodDropDown.Value, ...
                                ' smoothing on original data is completed.');
                            app.StatussmoothingLabel.Visible = 'on';
                            app.UIAxes_smoothing.Title.String = 'Subtracted smoothing mask from original data';
                  
                            
                        case 'Denoised data'
                            switch method
                                case 'Matlab Image Guided'
                                opts.filter = 'imguided';
                                app.data.densmoothData = filterData(app.data.densourceData, guideim, app.data.mask.WB, opts);
                  
                                case 'Bilateral'
                                opts.filter = 'bilateral';
                                opts.sigma_range = app.SigmarangeEditField.Value; 
                                app.data.densmoothData = filterData(app.data.densourceData, guideim, app.data.mask.WB, opts);
                                
                                case 'TIPS'    
                                opts.filter = 'tips';
                                opts.sigma_range = app.SigmarangeEditField.Value;
                                app.data.densmoothData = filterData(app.data.densourceData, guideim, app.data.mask.WB, opts);  
                                
                                case 'Standard Gaussian'
                                opts.FWHM = app.FWHM_x.Value;
                                app.data.densmoothData = smthData(app.data.densourceData, app.data.mask.WB, opts);
                            
                                case 'Gaussian'
                                opts.filter = 'gaussian';
                                app.data.densmoothData = filterData(app.data.densourceData, guideim, app.data.mask.WB, opts);  
                       
                            end
                            
                            app.StatussmoothingLabel.Text = append('Status: ', app.SmoothingmethodDropDown.Value, ...
                                ' smoothing on denoised data is completed.');
                            app.StatussmoothingLabel.Visible = 'on';
                            app.UIAxes_smoothing.Title.String = 'Subtracted smoothing mask from denoised data';
                                                    
                    end
                    
                catch 
                    app.StatussmoothingLabel.Text = 'Status: An error has occurred.';
                    app.SmoothingactLabel.Text = 'Smoothing: no';
                    app.SmoothingactLabel.FontWeight = 'normal';
                    app.ApplysmoothingButton.Enable = 'on';
                    
                end
                
            end
            
            switch app.ButtonGroup_sData.SelectedObject.Text      
                case 'Original data'
                    app.data.smthout = mean(app.data.sourceData - app.data.smoothData,4);
                    
                case 'Denoised data'
                    app.data.smthout = mean(app.data.densourceData - app.data.densmoothData,4).* logical(app.data.mask.WB);
            end   
            
            app.SmoothingactLabel.Text = 'Smoothing: yes';
            app.SmoothingactLabel.FontWeight = 'bold';

            %show image subtraction    
            ratio = size(app.data.smthout,1)/size(app.data.smthout,2);
            app.UIAxes_smoothing.PlotBoxAspectRatio = [1, ratio, 1];
            
            slice = app.SliceSlider_smoothing.Value;
            
            imagesc(app.UIAxes_smoothing, squeeze(app.data.smthout(:,:,slice)));
            app.UIAxes_smoothing.Colormap = bone(128);
            app.UIAxes_smoothing.Visible = 'on';
            app.SliceSlider_smoothing.Visible = 'on';
            app.smoothsliceLabel.Visible = 'on';
            app.SliceSliderLabel_2.Visible = 'on';

            app.ApplysmoothingButton.Enable = 'on';

        end

        % Button pushed function: RemovesmoothingButton
        function RemovesmoothingButtonPushed(app, event)
            app.SmoothingactLabel.Text = 'Smoothing: no';
            app.SmoothingactLabel.FontWeight = 'normal';
            app.StatussmoothingLabel.Text = 'Status: smoothing removed';
            app.data.smoothData = [];
            app.data.densmoothData = [];
            app.ApplysmoothingButton.Enable = 'on';
            cla(app.UIAxes_smoothing);
            app.UIAxes_smoothing.Visible = 'off';
            app.SliceSlider_smoothing.Visible = 'off';
            app.smoothsliceLabel.Visible = 'off';
            
        end

        % Value changing function: SliceSlider_smoothing
        function SliceSlider_smoothingValueChanging(app, event)
            changingValue = event.Value;
            slice = round(changingValue);
            app.smoothsliceLabel.Text = append('Current slice: ', int2str(slice));
            
            cla(app.UIAxes_smoothing);
            imagesc(app.UIAxes_smoothing, squeeze(app.data.smthout(:,:,slice)));        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'seeVR.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputdataTab = uitab(app.TabGroup);
            app.InputdataTab.Title = 'Input data';
            app.UIAxes_source = uiaxes(app.InputdataTab);
            app.UIAxes_source.XTick = [];
            app.UIAxes_source.YTick = [];
            app.UIAxes_source.Visible = 'off';
            app.UIAxes_mask = uiaxes(app.InputdataTab);
            app.UIAxes_mask.XTick = [];
            app.UIAxes_mask.YTick = [];
            app.UIAxes_mask.Visible = 'off';
            app.UIAxes_tSNR = uiaxes(app.InputdataTab);
            app.UIAxes_tSNR.CLim = [0 100];
            app.UIAxes_tSNR.XTick = [];
            app.UIAxes_tSNR.YTick = [];
            app.UIAxes_tSNR.Visible = 'off';
            app.Label = uilabel(app.InputdataTab);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.volume_Slider = uislider(app.InputdataTab);
            app.volume_Slider.Limits = [1 100];
            app.volume_Slider.MajorTicks = [];
            app.volume_Slider.ValueChangedFcn = createCallbackFcn(app, @volume_SliderValueChanged, true);
            app.volume_Slider.MinorTicks = [];
            app.volume_Slider.Visible = 'off';
            app.volume_Slider.Value = 1;
            app.Label_2 = uilabel(app.InputdataTab);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.slice_Slider = uislider(app.InputdataTab);
            app.slice_Slider.Limits = [1 100];
            app.slice_Slider.MajorTicks = [];
            app.slice_Slider.ValueChangedFcn = createCallbackFcn(app, @slice_SliderValueChanged, true);
            app.slice_Slider.MinorTicks = [];
            app.slice_Slider.Visible = 'off';
            app.slice_Slider.Value = 1;
            app.remLV = uibutton(app.InputdataTab, 'push');
            app.remLV.ButtonPushedFcn = createCallbackFcn(app, @remLVButtonPushed, true);
            app.remLV.Visible = 'off';
            app.remLV.Tooltip = {'voxels are sorted in terms of their noise-to-signal values and thresholded based on the supplied percentile cut-off value'};
            app.remLV.Text = 'remove large vessels';
            app.pctilecutoffEditFieldLabel = uilabel(app.InputdataTab);
            app.pctilecutoffEditFieldLabel.Visible = 'off';
            app.pctilecutoffEditFieldLabel.Text = 'pctile cut-off';
            app.pctileEdit = uieditfield(app.InputdataTab, 'text');
            app.pctileEdit.Visible = 'off';
            app.pctileEdit.Value = '98';
            app.OrientationDropDownLabel = uilabel(app.InputdataTab);
            app.OrientationDropDownLabel.Visible = 'off';
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.InputdataTab);
            app.OrientationDropDown.Items = {'ORI1', 'ORI2', 'ORI3'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Visible = 'off';
            app.OrientationDropDown.Tooltip = {'select image orientation'};
            app.OrientationDropDown.Value = 'ORI1';
            app.Label_3 = uilabel(app.InputdataTab);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.slice_Slider_2 = uislider(app.InputdataTab);
            app.slice_Slider_2.Limits = [1 100];
            app.slice_Slider_2.MajorTicks = [];
            app.slice_Slider_2.ValueChangedFcn = createCallbackFcn(app, @slice_Slider_2ValueChanged, true);
            app.slice_Slider_2.MinorTicks = [];
            app.slice_Slider_2.Visible = 'off';
            app.slice_Slider_2.Value = 1;
            app.DenoisingTab = uitab(app.TabGroup);
            app.DenoisingTab.Title = 'Denoising';
            app.UIAxes_noiseremoval = uiaxes(app.DenoisingTab);
            title(app.UIAxes_noiseremoval, 'Noise removal')
            xlabel(app.UIAxes_noiseremoval, 'time (s)')
            ylabel(app.UIAxes_noiseremoval, 'absolute signal')
            app.Panel_denoising = uipanel(app.DenoisingTab);
            app.DenoisingLabel = uilabel(app.Panel_denoising);
            app.DenoisingLabel.Text = 'Denoising';
            app.ApplydenButton = uibutton(app.Panel_denoising, 'push');
            app.ApplydenButton.ButtonPushedFcn = createCallbackFcn(app, @ApplydenoisingButtonPushed, true);
            app.ApplydenButton.Text = 'Apply denoising';
            app.RemprevdenButton = uibutton(app.Panel_denoising, 'push');
            app.RemprevdenButton.ButtonPushedFcn = createCallbackFcn(app, @RemovedenoisingButtonPushed, true);
            app.RemprevdenButton.Text = 'Reset denoising';
            app.DenoisingmethodDropDownLabel = uilabel(app.Panel_denoising);
            app.DenoisingmethodDropDownLabel.Text = 'Denoising method';
            app.DenoisingmethodDropDown = uidropdown(app.Panel_denoising);
            app.DenoisingmethodDropDown.Items = {'Wavelet', 'Moving window', 'Bandpass filter'};
            app.DenoisingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DenoisingmethodDropDownValueChanged, true);
            app.DenoisingmethodDropDown.Value = 'Wavelet';
            app.FrequencybandpassEditFieldLabel = uilabel(app.Panel_denoising);
            app.FrequencybandpassEditFieldLabel.Visible = 'off';
            app.FrequencybandpassEditFieldLabel.Text = 'Frequency bandpass';
            app.FrequencybpminEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.FrequencybpminEditField.Limits = [1e-06 1];
            app.FrequencybpminEditField.Visible = 'off';
            app.FrequencybpminEditField.Value = 1e-06;
            app.minLabel = uilabel(app.Panel_denoising);
            app.minLabel.Visible = 'off';
            app.minLabel.Text = 'min.';
            app.maxLabel = uilabel(app.Panel_denoising);
            app.maxLabel.Visible = 'off';
            app.maxLabel.Text = 'max.';
            app.FrequencybpmaxEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.FrequencybpmaxEditField.Limits = [1e-06 1];
            app.FrequencybpmaxEditField.Visible = 'off';
            app.FrequencybpmaxEditField.Value = 0.1164;
            app.FilterorderEditFieldLabel = uilabel(app.Panel_denoising);
            app.FilterorderEditFieldLabel.Visible = 'off';
            app.FilterorderEditFieldLabel.Text = 'Filter order';
            app.FilterorderEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.FilterorderEditField.Visible = 'off';
            app.MethodDropDown_2Label = uilabel(app.Panel_denoising);
            app.MethodDropDown_2Label.Visible = 'off';
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodmovDropDown = uidropdown(app.Panel_denoising);
            app.MethodmovDropDown.Items = {'movmean', 'movmedian', 'gaussian', 'lowess', 'loess', 'rlowess', 'rloess', 'sgolay'};
            app.MethodmovDropDown.Visible = 'off';
            app.MethodmovDropDown.Value = 'movmean';
            app.Smoothingfactor01EditFieldLabel = uilabel(app.Panel_denoising);
            app.Smoothingfactor01EditFieldLabel.Visible = 'off';
            app.Smoothingfactor01EditFieldLabel.Text = 'Smoothing factor (0-1)';
            app.Smoothingfactor01EditField = uieditfield(app.Panel_denoising, 'numeric');
            app.Smoothingfactor01EditField.Visible = 'off';
            app.Smoothingfactor01EditField.Value = 0.25;
            app.WindowsizeEditFieldLabel = uilabel(app.Panel_denoising);
            app.WindowsizeEditFieldLabel.Visible = 'off';
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.WindowsizeEditField.Visible = 'off';
            app.NoiseestLabel = uilabel(app.Panel_denoising);
            app.NoiseestLabel.Text = 'Noise estimation';
            app.ButtonGroup = uibuttongroup(app.Panel_denoising);
            app.LevelindepButton = uiradiobutton(app.ButtonGroup);
            app.LevelindepButton.Text = 'Level independent';
            app.LevelindepButton.Value = true;
            app.LeveldepButton = uiradiobutton(app.ButtonGroup);
            app.LeveldepButton.Text = 'Level dependent';
            app.ThresholdruleDropDownLabel = uilabel(app.Panel_denoising);
            app.ThresholdruleDropDownLabel.Text = 'Threshold rule';
            app.ThresruleDropDown = uidropdown(app.Panel_denoising);
            app.ThresruleDropDown.Items = {'Hard', 'Soft', 'Median', 'Mean'};
            app.ThresruleDropDown.Value = 'Hard';
            app.MethodDropDownLabel = uilabel(app.Panel_denoising);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodwavDropDown = uidropdown(app.Panel_denoising);
            app.MethodwavDropDown.Items = {'UniversalThreshold', 'Bayes', 'BlockJS', 'FDR', 'Minimax', 'SURE'};
            app.MethodwavDropDown.Value = 'UniversalThreshold';
            app.WaveletlevelEditFieldLabel = uilabel(app.Panel_denoising);
            app.WaveletlevelEditFieldLabel.Text = 'Wavelet level';
            app.WavlevelEditField = uieditfield(app.Panel_denoising, 'numeric');
            app.WavlevelEditField.Limits = [1 7];
            app.WavlevelEditField.RoundFractionalValues = 'on';
            app.WavlevelEditField.Value = 2;
            app.WaveletfamilyDropDownLabel = uilabel(app.Panel_denoising);
            app.WaveletfamilyDropDownLabel.Text = 'Wavelet family';
            app.WavfamDropDown = uidropdown(app.Panel_denoising);
            app.WavfamDropDown.Items = {'db', 'sym', 'fk', 'bior', 'coif'};
            app.WavfamDropDown.ValueChangedFcn = createCallbackFcn(app, @WavfamDropDownValueChanged, true);
            app.WavfamDropDown.Value = 'db';
            app.FamilynumberDropDownLabel = uilabel(app.Panel_denoising);
            app.FamilynumberDropDownLabel.Text = 'Family number';
            app.FamilynumberDropDown = uidropdown(app.Panel_denoising);
            app.FamilynumberDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.FamilynumberDropDown.Value = '4';
            app.WhichmaskDropDownLabel = uilabel(app.DenoisingTab);
            app.WhichmaskDropDownLabel.Text = 'Which mask?';
            app.WhichmaskDropDown = uidropdown(app.DenoisingTab);
            app.WhichmaskDropDown.Items = {'Whole brain'};
            app.WhichmaskDropDown.ValueChangedFcn = createCallbackFcn(app, @WhichmaskDropDownValueChanged, true);
            app.WhichmaskDropDown.Value = 'Whole brain';
            app.LicensestatusLabel = uilabel(app.DenoisingTab);
            app.LicensestatusLabel.Visible = 'off';
            app.LicensestatusLabel.Text = 'License status';
            app.ErrorLabel = uilabel(app.DenoisingTab);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.Denoising2Tab = uitab(app.TabGroup);
            app.Denoising2Tab.Title = 'Denoising 2';
            app.UIAxes_denData = uiaxes(app.Denoising2Tab);
            app.UIAxes_denData.XTick = [];
            app.UIAxes_denData.YTick = [];
            app.UIAxes_denVoxelGraph = uiaxes(app.Denoising2Tab);
            title(app.UIAxes_denVoxelGraph, 'Title')
            xlabel(app.UIAxes_denVoxelGraph, 'X')
            ylabel(app.UIAxes_denVoxelGraph, 'Y')
            app.PlotdenoisedimageButton = uibutton(app.Denoising2Tab, 'push');
            app.PlotdenoisedimageButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdenoisedimageButtonPushed, true);
            app.PlotdenoisedimageButton.Text = 'Plot denoised image';
            app.SliceSliderLabel = uilabel(app.Denoising2Tab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSliderDen = uislider(app.Denoising2Tab);
            app.SliceSliderDen.MajorTicks = [];
            app.SliceSliderDen.ValueChangingFcn = createCallbackFcn(app, @SliceSliderDenValueChanging, true);
            app.SliceSliderDen.MinorTicks = [];
            app.CurrentsliceLabel = uilabel(app.Denoising2Tab);
            app.CurrentsliceLabel.Text = 'Current slice: 1 ';
            app.SmoothingTab = uitab(app.TabGroup);
            app.SmoothingTab.Title = 'Smoothing';
            app.UIAxes_smoothing = uiaxes(app.SmoothingTab);
            title(app.UIAxes_smoothing, {'Subtracted smoothing mask from denoised data'; ''})
            app.UIAxes_smoothing.XTick = [];
            app.UIAxes_smoothing.YTick = [];
            app.UIAxes_smoothing.Visible = 'off';
            app.Panel_smoothing = uipanel(app.SmoothingTab);
            app.SmoothingLabel = uilabel(app.Panel_smoothing);
            app.SmoothingLabel.Text = 'Smoothing';
            app.xLabel = uilabel(app.Panel_smoothing);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.Panel_smoothing);
            app.yLabel.Text = 'y';
            app.FWHM_y = uieditfield(app.Panel_smoothing, 'numeric');
            app.FWHM_y.Limits = [0 Inf];
            app.FWHM_y.Value = 4;
            app.zLabel = uilabel(app.Panel_smoothing);
            app.zLabel.Visible = 'off';
            app.zLabel.Text = 'z';
            app.FWHM_z = uieditfield(app.Panel_smoothing, 'numeric');
            app.FWHM_z.Limits = [0 Inf];
            app.FWHM_z.Visible = 'off';
            app.FWHM_z.Value = 4;
            app.ApplysmoothingButton = uibutton(app.Panel_smoothing, 'push');
            app.ApplysmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplysmoothingButtonPushed, true);
            app.ApplysmoothingButton.Text = 'Apply smoothing';
            app.RemovesmoothingButton = uibutton(app.Panel_smoothing, 'push');
            app.RemovesmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @RemovesmoothingButtonPushed, true);
            app.RemovesmoothingButton.Text = 'Remove smoothing';
            app.SmoothingmethodDropDownLabel = uilabel(app.Panel_smoothing);
            app.SmoothingmethodDropDownLabel.Text = 'Smoothing method';
            app.SmoothingmethodDropDown = uidropdown(app.Panel_smoothing);
            app.SmoothingmethodDropDown.Items = {'Matlab Image Guided', 'Bilateral', 'TIPS', 'Standard Gaussian', 'Gaussian'};
            app.SmoothingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SmoothingmethodDropDownValueChanged, true);
            app.SmoothingmethodDropDown.Value = 'Matlab Image Guided';
            app.SpatialdimensionsDropDownLabel = uilabel(app.Panel_smoothing);
            app.SpatialdimensionsDropDownLabel.Text = 'Spatial dimensions';
            app.SpatialdimensionsDropDown = uidropdown(app.Panel_smoothing);
            app.SpatialdimensionsDropDown.Items = {'2D', '3D'};
            app.SpatialdimensionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SpatialdimensionsDropDownValueChanged, true);
            app.SpatialdimensionsDropDown.Value = '2D';
            app.FWHMofkernelmmEditFieldLabel = uilabel(app.Panel_smoothing);
            app.FWHMofkernelmmEditFieldLabel.Text = 'FWHM of kernel (mm)';
            app.FWHM_x = uieditfield(app.Panel_smoothing, 'numeric');
            app.FWHM_x.Limits = [0 Inf];
            app.FWHM_x.Value = 4;
            app.FilterwidthmmEditFieldLabel = uilabel(app.Panel_smoothing);
            app.FilterwidthmmEditFieldLabel.Text = 'Filter width (mm)';
            app.FilterwidthmmEditField = uieditfield(app.Panel_smoothing, 'numeric');
            app.FilterwidthmmEditField.Value = 5;
            app.SigmarangeEditFieldLabel = uilabel(app.Panel_smoothing);
            app.SigmarangeEditFieldLabel.Visible = 'off';
            app.SigmarangeEditFieldLabel.Text = 'Sigma range';
            app.SigmarangeEditField = uieditfield(app.Panel_smoothing, 'numeric');
            app.SigmarangeEditField.Visible = 'off';
            app.ButtonGroup_sData = uibuttongroup(app.Panel_smoothing);
            app.OriginaldataButton = uiradiobutton(app.ButtonGroup_sData);
            app.OriginaldataButton.Text = 'Original data';
            app.OriginaldataButton.Value = true;
            app.DenoiseddataButton = uiradiobutton(app.ButtonGroup_sData);
            app.DenoiseddataButton.Enable = 'off';
            app.DenoiseddataButton.Text = 'Denoised data';
            app.SmoothenLabel = uilabel(app.Panel_smoothing);
            app.SmoothenLabel.Text = 'Smoothen:';
            app.StatussmoothingLabel = uilabel(app.SmoothingTab);
            app.StatussmoothingLabel.Visible = 'off';
            app.StatussmoothingLabel.Text = {'Status:'; ''};
            app.SliceSliderLabel_2 = uilabel(app.SmoothingTab);
            app.SliceSliderLabel_2.Visible = 'off';
            app.SliceSliderLabel_2.Text = 'Slice';
            app.SliceSlider_smoothing = uislider(app.SmoothingTab);
            app.SliceSlider_smoothing.MajorTicks = [];
            app.SliceSlider_smoothing.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_smoothingValueChanging, true);
            app.SliceSlider_smoothing.MinorTicks = [];
            app.SliceSlider_smoothing.Visible = 'off';
            app.smoothsliceLabel = uilabel(app.SmoothingTab);
            app.smoothsliceLabel.Visible = 'off';
            app.smoothsliceLabel.Text = 'Current slice: ';
            app.NuisanceregressionTab = uitab(app.TabGroup);
            app.NuisanceregressionTab.Title = 'Nuisance regression';
            app.UIAxes = uiaxes(app.NuisanceregressionTab);
            title(app.UIAxes, 'Reference signal before and after scrubbing')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'absolute signal')
            app.LoadnuisanceparametersButton = uibutton(app.NuisanceregressionTab, 'push');
            app.LoadnuisanceparametersButton.Text = 'Load nuisance parameters';
            app.PerformnuisanceregressionButton = uibutton(app.NuisanceregressionTab, 'push');
            app.PerformnuisanceregressionButton.Text = 'Perform nuisance regression';
            app.CVRandhemodynamiclagTab = uitab(app.TabGroup);
            app.CVRandhemodynamiclagTab.Title = 'CVR and hemodynamic lag';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.selectinputdirectoryButton = uibutton(app.UIFigure, 'push');
            app.selectinputdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectinputdirectoryButtonPushed, true);
            app.selectinputdirectoryButton.Text = 'select input directory';
            app.registerfunctoanatButton = uibutton(app.UIFigure, 'push');
            app.registerfunctoanatButton.ButtonPushedFcn = createCallbackFcn(app, @registerfunctoanatButtonPushed, true);
            app.registerfunctoanatButton.Visible = 'off';
            app.registerfunctoanatButton.Text = 'register func to anat';
            app.DenoisingactLabel = uilabel(app.UIFigure);
            app.DenoisingactLabel.Text = 'Denoising: no';
            app.SmoothingactLabel = uilabel(app.UIFigure);
            app.SmoothingactLabel.Text = 'Smoothing: no';
            app.NuiregactLabel = uilabel(app.UIFigure);
            app.NuiregactLabel.Text = 'Nuisance regression: no';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seeVR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end